classdef Display_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Display1                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        UITable2                      matlab.ui.control.Table
        StudentScoresPanel            matlab.ui.container.Panel
        PredictionMethodsButtonGroup  matlab.ui.container.ButtonGroup
        PPCAButton                    matlab.ui.control.RadioButton
        KNNCourseButton               matlab.ui.control.RadioButton
        KNNStudentButton              matlab.ui.control.RadioButton
        AnalyzewithPCAButton          matlab.ui.control.Button
        NearestNeighborsButton        matlab.ui.control.Button
        PredictButton                 matlab.ui.control.Button
        SenioryearscoreCheckBox       matlab.ui.control.CheckBox
        ViewScoreDistributionButton   matlab.ui.control.Button
        SignoutButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        Dataset
        SearchID
        SearchDepartmentIdx % Description
        FileIdx
        StudentIdx
        CoursesName
        StudentScores
        SelectedColumn
        PredictionResult
        pcaResult
        ClearTable
        SeniorData
    end


    methods (Access = public)
    
        function addDisplay(app, ID, departmentIdx, gradeIdx, studentIdx, scores, names, data)
            app.Dataset = data;
            app.SearchID = ID;
            app.SearchDepartmentIdx = departmentIdx;
            app.FileIdx = gradeIdx;
            app.StudentIdx = studentIdx;
            app.StudentScores = scores;
            app.CoursesName = names;
            app.ClearTable = 0;
            if gradeIdx ~= 1
                set(app.SenioryearscoreCheckBox, 'Visible', 'off');
            end
            
            set(app.UITable, 'Data', app.StudentScores);
            set(app.UITable, 'ColumnName', app.CoursesName);
            set(app.UITable2, 'ColumnName', app.CoursesName);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('ddataset.mat');
            app.SeniorData = ddataset;
        end

        % Callback function
        function CreatestudentdataButtonPushed(app, event)
            
        end

        % Callback function
        function InterpreteachcomponentButtonPushed(app, event)
            
        end

        % Callback function
        function NearestNeighborsButtonPushed(app, event)
            isStudent = get(app.KNNStudentButton, 'Value');
            isChecked = get(app.SenioryearscoreCheckBox, 'Value');
            if isStudent && ~isChecked
                set(app.AnalyzewithPCAButton, 'Enable', 'off');
                result = predictStudentScore(app.StudentIdx, app.SearchDepartmentIdx, app.FileIdx, 2, app.Dataset, 1);
                set(app.UITable2, 'Data', abs(round(result.NN)));
                set(app.AnalyzewithPCAButton, 'Enable', 'off');
                app.ClearTable = 1;
            end
        end

        % Callback function
        function PredictButtonPushed(app, event)
            isChecked = get(app.SenioryearscoreCheckBox, 'Value');
            set(app.AnalyzewithPCAButton, 'Enable', 'off');
            if app.ClearTable
                app.ClearTable = 0;
                set(app.UITable2, 'Data', []);
                set(app.UITable2, 'ColumnName', app.CoursesName);
            end
            isPPCA = get(app.PPCAButton, 'Value');
            isCourse = get(app.KNNCourseButton, 'Value');
            isStudent = get(app.KNNStudentButton, 'Value');
            if isPPCA
                if isChecked
                    Sresult = PredictSeniorScore(app.SearchDepartmentIdx, 1, app.SeniorData);
                    set(app.UITable2, 'ColumnName', Sresult.name);
                    set(app.UITable2, 'Data', abs(round(Sresult.Pred(app.StudentIdx, :))));
                    app.ClearTable = 1;
                else
                    result = predictStudentScore(app.StudentIdx, app.SearchDepartmentIdx, app.FileIdx, 1, app.Dataset, 1);
                    oldData = get(app.UITable2, 'Data');
                    newData = [oldData; abs(round(result.Pred(app.StudentIdx, :)))];
                    set(app.UITable2, 'Data', newData);
                    set(app.AnalyzewithPCAButton, 'Enable', 'on');
                    app.PredictionResult = result;
                end
            elseif isCourse
                if isChecked
                    Sresult = PredictSeniorScore(app.SearchDepartmentIdx, 3, app.SeniorData);
                    set(app.UITable2, 'ColumnName', Sresult.name);
                    set(app.UITable2, 'Data', abs(round(Sresult.Pred(app.StudentIdx, :))));
                    app.ClearTable = 1;
                else
                    result = predictStudentScore(app.StudentIdx, app.SearchDepartmentIdx, app.FileIdx, 3, app.Dataset, 1);
                    oldData = get(app.UITable2, 'Data');
                    newData = [oldData; abs(round(result.Pred(app.StudentIdx, :)))];      
                    set(app.UITable2, 'Data', newData);
                    set(app.AnalyzewithPCAButton, 'Enable', 'on');
                    app.PredictionResult = result;
                end
            elseif isStudent
                if isChecked
                    Sresult = PredictSeniorScore(app.SearchDepartmentIdx, 2, app.SeniorData);
                    set(app.UITable2, 'ColumnName', Sresult.name);
                    set(app.UITable2, 'Data', abs(round(Sresult.Pred(app.StudentIdx, :))));
                    app.ClearTable = 1;
                else
                    result = predictStudentScore(app.StudentIdx, app.SearchDepartmentIdx, app.FileIdx, 2, app.Dataset, 1);
                    oldData = get(app.UITable2, 'Data');
                    newData = [oldData; abs(round(result.Pred(app.StudentIdx, :)))];
                    set(app.UITable2, 'Data', newData);
                    set(app.AnalyzewithPCAButton, 'Enable', 'on');
                    app.PredictionResult = result;
                end
            end
        end

        % Callback function
        function SearchButtonPushed(app, event)
            
        end

        % Button pushed function: SignoutButton
        function SignoutButtonPushed(app, event)
            Login;
            close(app.Display1);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedColumn = event.Indices;
            set(app.ViewScoreDistributionButton, 'Enable', 'on');
        end

        % Callback function
        function ViewRecommendedCoreCoursesButtonPushed(app, event)
            app.pcaResult = CoreCourses(app.PredictionResult, app.SearchDepartmentIdx);
            InterpretComponents(app.pcaResult, app.StudentScores, app.CoursesName, app.SearchDepartmentIdx);
        end

        % Callback function
        function ViewScoreDistributionButtonPushed(app, event)
            if ~isempty(app.SearchID) && app.StudentIdx>0
                singleCourseHistogram(app.SelectedColumn, app.SearchDepartmentIdx, app.FileIdx, app.Dataset);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Display1 = uifigure;
            app.Display1.Name = 'UI Figure';
            app.UITable = uitable(app.Display1);
            app.UITable.ColumnName = {'Course 1'; 'Course 2'; 'Course 3'; 'Course 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable2 = uitable(app.Display1);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.StudentScoresPanel = uipanel(app.Display1);
            app.StudentScoresPanel.Title = 'Student Scores';
            app.PredictionMethodsButtonGroup = uibuttongroup(app.Display1);
            app.PredictionMethodsButtonGroup.Title = 'Prediction Methods';
            app.PPCAButton = uiradiobutton(app.PredictionMethodsButtonGroup);
            app.PPCAButton.Text = 'PPCA';
            app.PPCAButton.Value = true;
            app.KNNCourseButton = uiradiobutton(app.PredictionMethodsButtonGroup);
            app.KNNCourseButton.Text = 'KNN Course';
            app.KNNStudentButton = uiradiobutton(app.PredictionMethodsButtonGroup);
            app.KNNStudentButton.Text = 'KNN Student';
            app.AnalyzewithPCAButton = uibutton(app.PredictionMethodsButtonGroup, 'push');
            app.AnalyzewithPCAButton.ButtonPushedFcn = createCallbackFcn(app, @ViewRecommendedCoreCoursesButtonPushed, true);
            app.AnalyzewithPCAButton.Enable = 'off';
            app.AnalyzewithPCAButton.Text = 'Analyze with PCA';
            app.NearestNeighborsButton = uibutton(app.PredictionMethodsButtonGroup, 'push');
            app.NearestNeighborsButton.ButtonPushedFcn = createCallbackFcn(app, @NearestNeighborsButtonPushed, true);
            app.NearestNeighborsButton.Text = 'Nearest Neighbors';
            app.PredictButton = uibutton(app.PredictionMethodsButtonGroup, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.SenioryearscoreCheckBox = uicheckbox(app.PredictionMethodsButtonGroup);
            app.SenioryearscoreCheckBox.Text = 'Senior year score';
            app.ViewScoreDistributionButton = uibutton(app.Display1, 'push');
            app.ViewScoreDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ViewScoreDistributionButtonPushed, true);
            app.ViewScoreDistributionButton.Enable = 'off';
            app.ViewScoreDistributionButton.Text = 'View Score Distribution';
            app.SignoutButton = uibutton(app.Display1, 'push');
            app.SignoutButton.ButtonPushedFcn = createCallbackFcn(app, @SignoutButtonPushed, true);
            app.SignoutButton.Text = 'Sign out';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Display_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Display1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Display1)
        end
    end
end