classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginFigure              matlab.ui.Figure
        StudentIDEditFieldLabel  matlab.ui.control.Label
        StudentIDEditField       matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        SigninButton             matlab.ui.control.Button
        DepartmentDropDownLabel  matlab.ui.control.Label
        DepartmentDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        Dataset
        SearchID
        SearchDepartmentIdx
        StudentIdx
        CoursesName
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Dataset = importdata('DataSet_all_namesplit.mat');
        end

        % Button pushed function: SigninButton
        function SigninButtonPushed(app, event)
            app.SearchID = str2num(get(app.StudentIDEditField, 'Value'));
            app.SearchDepartmentIdx = str2num(get(app.DepartmentDropDown, 'Value'));
            if ~isempty(app.SearchID)
                [found, studentScores, app.StudentIdx, app.CoursesName] = searchData(app.SearchID, app.SearchDepartmentIdx, app.Dataset);
                if(found > 0)
                    displayApp = Display_v1;
                    displayApp.addDisplay(app.SearchID, app.SearchDepartmentIdx, found, app.StudentIdx, studentScores, app.CoursesName, app.Dataset);
                    close(app.LoginFigure);
                else
                    f = warndlg('ID Not Found', 'warning');
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginFigure = uifigure;
            app.LoginFigure.Name = 'UI Figure';
            app.StudentIDEditFieldLabel = uilabel(app.LoginFigure);
            app.StudentIDEditFieldLabel.Text = 'Student ID';
            app.StudentIDEditField = uieditfield(app.LoginFigure, 'text');
            app.Label = uilabel(app.LoginFigure);
            app.Label.Text = '學生修課分析系統';
            app.SigninButton = uibutton(app.LoginFigure, 'push');
            app.SigninButton.ButtonPushedFcn = createCallbackFcn(app, @SigninButtonPushed, true);
            app.SigninButton.Text = 'Sign in';
            app.DepartmentDropDownLabel = uilabel(app.LoginFigure);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.LoginFigure);
            app.DepartmentDropDown.Items = {'資工系', '機械系', '勞工系', '資管系'};
            app.DepartmentDropDown.ItemsData = {'1', '2', '3', '4'};
            app.DepartmentDropDown.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Login

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginFigure)
        end
    end
end